classdef interfaz_v2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PatientsMedicalSurveyUIFigure   matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        ValoresdeReferenciaLabel        matlab.ui.control.Label
        FrecuenciaEditFieldLabel        matlab.ui.control.Label
        FrecuenciaEditField             matlab.ui.control.NumericEditField
        DistanciaEditFieldLabel         matlab.ui.control.Label
        DistanciaEditField              matlab.ui.control.NumericEditField
        GHzLabel                        matlab.ui.control.Label
        KmLabel                         matlab.ui.control.Label
        FijarDatosButton                matlab.ui.control.Button
        Image                           matlab.ui.control.Image
        RightPanel                      matlab.ui.container.Panel
        TabGroup3                       matlab.ui.container.TabGroup
        PerdidasTab                     matlab.ui.container.Tab
        TabGroup4                       matlab.ui.container.TabGroup
        BasicasTab                      matlab.ui.container.Tab
        CalcularButton_2                matlab.ui.control.Button
        libre                           matlab.ui.control.NumericEditField
        dBLabel_4                       matlab.ui.control.Label
        DesapuntamientoTab              matlab.ui.container.Tab
        DesapenTxEditFieldLabel         matlab.ui.control.Label
        DesapenTxEditField              matlab.ui.control.NumericEditField
        CalcularButton_3                matlab.ui.control.Button
        DesapenRxEditFieldLabel         matlab.ui.control.Label
        DesapenRxEditField              matlab.ui.control.NumericEditField
        Ancho3dBEditFieldLabel          matlab.ui.control.Label
        Ancho3dBEditField               matlab.ui.control.NumericEditField
        desap                           matlab.ui.control.NumericEditField
        dBLabel_5                       matlab.ui.control.Label
        Label_5                         matlab.ui.control.Label
        Label_6                         matlab.ui.control.Label
        Label_7                         matlab.ui.control.Label
        GasesTab_2                      matlab.ui.container.Tab
        CalcularButton_4                matlab.ui.control.Button
        pgases                          matlab.ui.control.NumericEditField
        dBLabel_6                       matlab.ui.control.Label
        PresionEditFieldLabel           matlab.ui.control.Label
        PresionEditField                matlab.ui.control.NumericEditField
        DensidaddelAguaEditFieldLabel   matlab.ui.control.Label
        DensidadEditField               matlab.ui.control.NumericEditField
        TemperaturaEditField_2Label     matlab.ui.control.Label
        TemperaturaEditField_2          matlab.ui.control.NumericEditField
        hPaLabel_2                      matlab.ui.control.Label
        gm3Label_2                      matlab.ui.control.Label
        CLabel_2                        matlab.ui.control.Label
        LluviaTab_2                     matlab.ui.container.Tab
        FactorKEditFieldLabel           matlab.ui.control.Label
        FactorKEditField                matlab.ui.control.NumericEditField
        FactorAEditFieldLabel           matlab.ui.control.Label
        FactorAEditField                matlab.ui.control.NumericEditField
        AlturahsEditFieldLabel          matlab.ui.control.Label
        AlturahsEditField               matlab.ui.control.NumericEditField
        h0EditFieldLabel                matlab.ui.control.Label
        h0EditField                     matlab.ui.control.NumericEditField
        IndiponibilidadpEditFieldLabel  matlab.ui.control.Label
        IndiponibilidadpEditField       matlab.ui.control.NumericEditField
        IntensidadLluviaEditFieldLabel  matlab.ui.control.Label
        IntensidadLluviaEditField       matlab.ui.control.NumericEditField
        KmLabel_2                       matlab.ui.control.Label
        Label_8                         matlab.ui.control.Label
        mmhLabel                        matlab.ui.control.Label
        CalcularButton_6                matlab.ui.control.Button
        plluvia                         matlab.ui.control.NumericEditField
        dBLabel_8                       matlab.ui.control.Label
        LatitudEditFieldLabel           matlab.ui.control.Label
        LatitudEditField                matlab.ui.control.NumericEditField
        LongitudEditFieldLabel          matlab.ui.control.Label
        LongitudEditField               matlab.ui.control.NumericEditField
        ElevacionEditFieldLabel         matlab.ui.control.Label
        ElevacionEditField              matlab.ui.control.NumericEditField
        Label_9                         matlab.ui.control.Label
        OtrasPerdTab                    matlab.ui.container.Tab
        ElevacinEditFieldLabel          matlab.ui.control.Label
        ElevacinEditField               matlab.ui.control.NumericEditField
        Label_10                        matlab.ui.control.Label
        CalcularOtrasper                matlab.ui.control.Button
        ResultOtrasper                  matlab.ui.control.NumericEditField
        TotalTab_2                      matlab.ui.container.Tab
        CalcularButton_5                matlab.ui.control.Button
        ptotal                          matlab.ui.control.NumericEditField
        dBLabel_7                       matlab.ui.control.Label
        CNTab                           matlab.ui.container.Tab
        BalancedelEnlaceTab             matlab.ui.container.Tab
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        Data % Patient data
    end
    
    methods (Access = private)
        
        function enableForm(app)
            % Enable form elements
            app.NameEditField.Enable = 'on';
            app.AgeEditField.Enable = 'on';
            app.GenderDropDown.Enable = 'on';
            app.HealthStatusDropDown.Enable = 'on';
            app.SmokerCheckBox.Enable = 'on';

           app.NameEditFieldLabel.Enable = 'on';            
            app.AgeEditFieldLabel.Enable = 'on';
           app.GenderDropDownLabel.Enable = 'on';
            app.HealthStatusDropDownLabel.Enable = 'on';
        end
        
        function disableForm(app)
            % Set form fields to default values
            app.NameEditField.Value = '';
            app.AgeEditField.Value = 0;
            app.GenderDropDown.Value = 'Male';
            app.HealthStatusDropDown.Value = 'Excellent';
            app.SmokerCheckBox.Value = false;
            
            % Disable form fields
            app.NameEditField.Enable = 'off';
            app.AgeEditField.Enable = 'off';
            app.GenderDropDown.Enable = 'off';
            app.HealthStatusDropDown.Enable = 'off';
            app.SmokerCheckBox.Enable = 'off'; 
                        
            app.NameEditFieldLabel.Enable = 'off';            
            app.AgeEditFieldLabel.Enable = 'off';
            app.GenderDropDownLabel.Enable = 'off';
            app.HealthStatusDropDownLabel.Enable = 'off';
        end
    
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.PatientsMedicalSurveyUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {385, 385};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {253, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Close request function: PatientsMedicalSurveyUIFigure
        function CalcularButtonPushed(app, event)

           % if app.FrecuenciaEditField.Value > 50
                %f = app.UIFigure;
                %uialert(f,'Valor de frecuencia invalida, deber ser menor de 50','Error');
            %end
           % result = 32.45 + 20*log10(app.FrecuenciaEditField.Value*1000) ...
               % + 20*log10(app.DistanciaEditField.Value);            
            %app.ResultLfs.Value = result;
        end

        % Callback function
        function CalcularDesaButtonPushed(app, event)
            result = 12*(app.DesapuenTxEditField.Value/app.AnchoHazenTxEditField.Value)^2 + ...
            12*(app.DesapuenRxEditField.Value/app.DesapuenRxEditField.Value)^2;
           
            app.ResultDesap.Value = result;
        end

        % Callback function
        function Calcular_aten_gasesButtonPushed(app, event)
            try
            result = atenuacionGases(app.PresinEditField.Value,...
                app.TemperaturaEditField.Value, app.DensidaddelaguaEditField.Value,...
                app.DistanciaEditField.Value,app.FrecuenciaEditField.Value);
            app.ResultGases.Value = result;
        catch
            msgbox('Datos erroneos', 'Error','error');
            end 
        end

        % Button pushed function: FijarDatosButton
        function FijarDatosButtonPushed(app, event)
            if app.FrecuenciaEditField.Value < 56 && (app.FrecuenciaEditField.Value ~= 0 || app.DistanciaEditField.Value ~= 0 )
                app.TabGroup3.Visible = 'on';
            elseif app.FrecuenciaEditField.Value >= 56
                    msgbox('Frecuncia invalida, debe ingresar una frecuncia menor de 56GHz', 'Error','error');
                    app.FrecuenciaEditField.Value = 0;                
                elseif app.FrecuenciaEditField.Value == 0 || app.DistanciaEditField.Value == 0 
                    msgbox('Valores en cero', 'Error','error');
               end             
            
        end

        % Callback function
        function CalcularTotalButtonPushed(app, event)
            app.ResultTotal.Value = app.ResultLfs.Value + app.ResultDesap.Value...
                + app.ResultGases.Value;
        end

        % Button pushed function: CalcularButton_2
        function CalcularButton_2Pushed(app, event)
            result = 32.45 + 20*log10(app.FrecuenciaEditField.Value*1000) ...
                + 20*log10(app.DistanciaEditField.Value);            
            app.libre.Value = result;
        end

        % Button pushed function: CalcularButton_3
        function CalcularButton_3Pushed(app, event)
            result = 12*(app.DesapenTxEditField.Value/app.Ancho3dBEditField.Value)^2 + ...
            12*(app.DesapenRxEditField.Value/app.Ancho3dBEditField.Value)^2;
           
            app.desap.Value = result;
        end

        % Button pushed function: CalcularButton_4
        function CalcularButton_4Pushed(app, event)
            try
            result = atenuacionGases(app.PresionEditField.Value,...
                app.TemperaturaEditField_2.Value, app.DensidadEditField.Value,...
                app.DistanciaEditField.Value,app.FrecuenciaEditField.Value);
            app.pgases.Value = result;
        catch
            msgbox('Datos erroneos', 'Error','error');
            end 
        end

        % Button pushed function: CalcularButton_5
        function CalcularButton_5Pushed(app, event)
            %app.ptotal.Value = app.libre.Value + app.desap.Value...
               % + app.pgases.Value+app.plluvia.Value;
            
                a=app.libre.Value;
                b=app.desap.Value;
                c=app.pgases.Value;
                d=app.plluvia.Value;
                %agregar nuevas perdidas
                app.ptotal.Value=10*log10( 10^(a/10) +10^(b/10) +10^(c/10) +10^(d/10) );
        end

        % Button pushed function: CalcularButton_6
        function CalcularButton_6Pushed(app, event)
            app.plluvia.Value=perdidas_lluvia(app.FrecuenciaEditField.Value,app.LatitudEditField.Value,...
                app.LongitudEditField.Value, app.ElevacionEditField.Value, ...
             app.IntensidadLluviaEditField.Value, app.FactorKEditField.Value, app.FactorAEditField.Value,...
             app.AlturahsEditField.Value, app.h0EditField.Value, app.IndiponibilidadpEditField.Value);
        end

        % Button pushed function: CalcularOtrasper
        function CalcularOtrasperButtonPushed(app, event)
            if (app.FrecuenciaEditField.Value >= 10)
                if 5 < app.ElevacinEditField.Value < 10
                    app.ResultOtrasper.Value = 0.3;
                elseif app.ElevacinEditField.Value <= 5                    
                    app.ResultOtrasper.Value = 10;
                elseif app.ElevacinEditField.Value >= 10
                    app.ResultOtrasper.Value = 0;
                end
            else
                app.ResultOtrasper.Value = 0;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PatientsMedicalSurveyUIFigure = uifigure('Visible', 'off');
            app.PatientsMedicalSurveyUIFigure.Name = 'SIMULADOR SATELITAL';
            app.PatientsMedicalSurveyUIFigure.CloseRequestFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.PatientsMedicalSurveyUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.PatientsMedicalSurveyUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LeftPanel.Title = 'GRUPO SPUTNIK';
            app.ValoresdeReferenciaLabel = uilabel(app.LeftPanel);
            app.ValoresdeReferenciaLabel.Text = 'Valores de Referencia:';
            app.FrecuenciaEditFieldLabel = uilabel(app.LeftPanel);
            app.FrecuenciaEditFieldLabel.Text = 'Frecuencia';
            app.FrecuenciaEditField = uieditfield(app.LeftPanel, 'numeric');
            app.DistanciaEditFieldLabel = uilabel(app.LeftPanel);
            app.DistanciaEditFieldLabel.Text = 'Distancia';
            app.DistanciaEditField = uieditfield(app.LeftPanel, 'numeric');
            app.GHzLabel = uilabel(app.LeftPanel);
            app.GHzLabel.Text = '  GHz';
            app.KmLabel = uilabel(app.LeftPanel);
            app.KmLabel.Text = '  Km';
            app.FijarDatosButton = uibutton(app.LeftPanel, 'push');
            app.FijarDatosButton.ButtonPushedFcn = createCallbackFcn(app, @FijarDatosButtonPushed, true);
            app.FijarDatosButton.Text = 'Fijar Datos';
            app.Image = uiimage(app.LeftPanel);
            app.Image.ImageSource = 'test.PNG';
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup3 = uitabgroup(app.RightPanel);
            app.PerdidasTab = uitab(app.TabGroup3);
            app.PerdidasTab.Title = 'Perdidas';
            app.TabGroup4 = uitabgroup(app.PerdidasTab);
            app.BasicasTab = uitab(app.TabGroup4);
            app.BasicasTab.Title = 'Basicas';
            app.CalcularButton_2 = uibutton(app.BasicasTab, 'push');
            app.CalcularButton_2.ButtonPushedFcn = createCallbackFcn(app, @CalcularButton_2Pushed, true);
            app.CalcularButton_2.Text = 'Calcular';
            app.libre = uieditfield(app.BasicasTab, 'numeric');
            app.dBLabel_4 = uilabel(app.BasicasTab);
            app.dBLabel_4.Text = 'dB';
            app.DesapuntamientoTab = uitab(app.TabGroup4);
            app.DesapuntamientoTab.Title = 'Desapuntamiento';
            app.DesapenTxEditFieldLabel = uilabel(app.DesapuntamientoTab);
            app.DesapenTxEditFieldLabel.Text = 'Desap en Tx';
            app.DesapenTxEditField = uieditfield(app.DesapuntamientoTab, 'numeric');
            app.CalcularButton_3 = uibutton(app.DesapuntamientoTab, 'push');
            app.CalcularButton_3.ButtonPushedFcn = createCallbackFcn(app, @CalcularButton_3Pushed, true);
            app.CalcularButton_3.Text = 'Calcular';
            app.DesapenRxEditFieldLabel = uilabel(app.DesapuntamientoTab);
            app.DesapenRxEditFieldLabel.Text = 'Desap en Rx';
            app.DesapenRxEditField = uieditfield(app.DesapuntamientoTab, 'numeric');
            app.Ancho3dBEditFieldLabel = uilabel(app.DesapuntamientoTab);
            app.Ancho3dBEditFieldLabel.Text = ' Ancho 3dB';
            app.Ancho3dBEditField = uieditfield(app.DesapuntamientoTab, 'numeric');
            app.desap = uieditfield(app.DesapuntamientoTab, 'numeric');
            app.dBLabel_5 = uilabel(app.DesapuntamientoTab);
            app.dBLabel_5.Text = 'dB';
            app.Label_5 = uilabel(app.DesapuntamientoTab);
            app.Label_5.Text = '°';
            app.Label_6 = uilabel(app.DesapuntamientoTab);
            app.Label_6.Text = '°';
            app.Label_7 = uilabel(app.DesapuntamientoTab);
            app.Label_7.Text = '°';
            app.GasesTab_2 = uitab(app.TabGroup4);
            app.GasesTab_2.Title = 'Gases';
            app.CalcularButton_4 = uibutton(app.GasesTab_2, 'push');
            app.CalcularButton_4.ButtonPushedFcn = createCallbackFcn(app, @CalcularButton_4Pushed, true);
            app.CalcularButton_4.Text = 'Calcular';
            app.pgases = uieditfield(app.GasesTab_2, 'numeric');
            app.dBLabel_6 = uilabel(app.GasesTab_2);
            app.dBLabel_6.Text = 'dB';
            app.PresionEditFieldLabel = uilabel(app.GasesTab_2);
            app.PresionEditFieldLabel.Text = 'Presion';
            app.PresionEditField = uieditfield(app.GasesTab_2, 'numeric');
            app.DensidaddelAguaEditFieldLabel = uilabel(app.GasesTab_2);
            app.DensidaddelAguaEditFieldLabel.Text = 'Densidad del Agua';
            app.DensidadEditField = uieditfield(app.GasesTab_2, 'numeric');
            app.TemperaturaEditField_2Label = uilabel(app.GasesTab_2);
            app.TemperaturaEditField_2Label.Text = 'Temperatura';
            app.TemperaturaEditField_2 = uieditfield(app.GasesTab_2, 'numeric');
            app.hPaLabel_2 = uilabel(app.GasesTab_2);
            app.hPaLabel_2.Text = '  hPa';
            app.gm3Label_2 = uilabel(app.GasesTab_2);
            app.gm3Label_2.Text = '  g/m^3';
            app.CLabel_2 = uilabel(app.GasesTab_2);
            app.CLabel_2.Text = '  °C';
            app.LluviaTab_2 = uitab(app.TabGroup4);
            app.LluviaTab_2.Title = 'Lluvia';
            app.FactorKEditFieldLabel = uilabel(app.LluviaTab_2);
            app.FactorKEditFieldLabel.Text = 'Factor K';
            app.FactorKEditField = uieditfield(app.LluviaTab_2, 'numeric');
            app.FactorAEditFieldLabel = uilabel(app.LluviaTab_2);
            app.FactorAEditFieldLabel.Text = 'Factor A';
            app.FactorAEditField = uieditfield(app.LluviaTab_2, 'numeric');
            app.AlturahsEditFieldLabel = uilabel(app.LluviaTab_2);
            app.AlturahsEditFieldLabel.Text = 'Altura (hs)';
            app.AlturahsEditField = uieditfield(app.LluviaTab_2, 'numeric');
            app.h0EditFieldLabel = uilabel(app.LluviaTab_2);
            app.h0EditFieldLabel.Text = 'h0';
            app.h0EditField = uieditfield(app.LluviaTab_2, 'numeric');
            app.IndiponibilidadpEditFieldLabel = uilabel(app.LluviaTab_2);
            app.IndiponibilidadpEditFieldLabel.Text = 'Indiponibilidad (p)';
            app.IndiponibilidadpEditField = uieditfield(app.LluviaTab_2, 'numeric');
            app.IntensidadLluviaEditFieldLabel = uilabel(app.LluviaTab_2);
            app.IntensidadLluviaEditFieldLabel.Text = 'Intensidad Lluvia';
            app.IntensidadLluviaEditField = uieditfield(app.LluviaTab_2, 'numeric');
            app.KmLabel_2 = uilabel(app.LluviaTab_2);
            app.KmLabel_2.Text = 'Km';
            app.Label_8 = uilabel(app.LluviaTab_2);
            app.Label_8.Text = '%';
            app.mmhLabel = uilabel(app.LluviaTab_2);
            app.mmhLabel.Text = 'mm/h';
            app.CalcularButton_6 = uibutton(app.LluviaTab_2, 'push');
            app.CalcularButton_6.ButtonPushedFcn = createCallbackFcn(app, @CalcularButton_6Pushed, true);
            app.CalcularButton_6.Text = 'Calcular';
            app.plluvia = uieditfield(app.LluviaTab_2, 'numeric');
            app.dBLabel_8 = uilabel(app.LluviaTab_2);
            app.dBLabel_8.Text = 'dB';
            app.LatitudEditFieldLabel = uilabel(app.LluviaTab_2);
            app.LatitudEditFieldLabel.Text = 'Latitud';
            app.LatitudEditField = uieditfield(app.LluviaTab_2, 'numeric');
            app.LongitudEditFieldLabel = uilabel(app.LluviaTab_2);
            app.LongitudEditFieldLabel.Text = 'Longitud';
            app.LongitudEditField = uieditfield(app.LluviaTab_2, 'numeric');
            app.ElevacionEditFieldLabel = uilabel(app.LluviaTab_2);
            app.ElevacionEditFieldLabel.Text = 'Elevacion';
            app.ElevacionEditField = uieditfield(app.LluviaTab_2, 'numeric');
            app.Label_9 = uilabel(app.LluviaTab_2);
            app.Label_9.Text = '°';
            app.OtrasPerdTab = uitab(app.TabGroup4);
            app.OtrasPerdTab.Title = 'Otras Perd';
            app.ElevacinEditFieldLabel = uilabel(app.OtrasPerdTab);
            app.ElevacinEditFieldLabel.Text = 'Elevación';
            app.ElevacinEditField = uieditfield(app.OtrasPerdTab, 'numeric');
            app.Label_10 = uilabel(app.OtrasPerdTab);
            app.Label_10.Text = '°';
            app.ResultOtrasper = uieditfield(app.OtrasPerdTab, 'numeric');
            app.CalcularOtrasper = uibutton(app.OtrasPerdTab, 'push');
            app.CalcularOtrasper.ButtonPushedFcn = createCallbackFcn(app, @CalcularOtrasperButtonPushed, true);
            app.CalcularOtrasper.Text = 'Calcular';
            app.TotalTab_2 = uitab(app.TabGroup4);
            app.TotalTab_2.Title = 'Total';
            app.CalcularButton_5 = uibutton(app.TotalTab_2, 'push');
            app.CalcularButton_5.ButtonPushedFcn = createCallbackFcn(app, @CalcularButton_5Pushed, true);
            app.CalcularButton_5.Text = 'Calcular';
            app.ptotal = uieditfield(app.TotalTab_2, 'numeric');
            app.dBLabel_7 = uilabel(app.TotalTab_2);
            app.dBLabel_7.Text = 'dB';
            app.CNTab = uitab(app.TabGroup3);
            app.CNTab.Title = 'C/N';
            app.BalancedelEnlaceTab = uitab(app.TabGroup3);
            app.BalancedelEnlaceTab.Title = 'Balance del Enlace';
            % Show the figure after all components are created
            app.PatientsMedicalSurveyUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = interfaz_v2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PatientsMedicalSurveyUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PatientsMedicalSurveyUIFigure)
        end
    end
end